classdef TradingStrategies < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StarttotradeButton              matlab.ui.control.StateButton
        PricefluctuationsDropDown       matlab.ui.control.DropDown
        PricefluctuationsDropDownLabel  matlab.ui.control.Label
        StablepriceDropDown             matlab.ui.control.DropDown
        StablepriceDropDownLabel        matlab.ui.control.Label
        DecreaseinpriceDropDown         matlab.ui.control.DropDown
        DecreaseinpriceDropDownLabel    matlab.ui.control.Label
        IncreaseinpriceDropDown         matlab.ui.control.DropDown
        IncreaseinpriceDropDownLabel    matlab.ui.control.Label
        TradingStrategiesLabel          matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
    app.UIFigure.Visible = 'off';
    movegui(app.UIFigure,"center")
    app.UIFigure.Visible = 'on';  
        end

        % Value changed function: StarttotradeButton
        function StarttotradeButtonValueChanged(app, event)
           incr = app.IncreaseinpriceDropDown.Value;
           decr = app.DecreaseinpriceDropDown.Value;
           stab = app.StablepriceDropDown.Value;
           fluc = app.PricefluctuationsDropDown.Value;

           if incr == "Not interested"
               %app.Lamp.Color = "red";
           elseif incr == "Bull Spread"
               %app.Lamp.Color = "green";
               !child_trading_bull.mlapp
           end
           
           if decr == "Not interested"
               %app.Lamp_2.Color = "red";
           elseif decr == "Bear Spread"
               %app.Lamp_2.Color = "green";
               !child_trading_bear.mlapp
           end
           
           if stab == "Not interested"
               %app.Lamp_3.Color = "red";
           elseif stab == "Long Butterfly Spread"
               %app.Lamp_3.Color = "green";
               !child_trading_long_butterfly.mlapp
           elseif stab == "Short Straddle"
               %app.Lamp_3.Color = "green";
               !child_trading_short_Straddle.mlapp
           elseif stab == "Short Strap"
               %app.Lamp_3.Color = "green";
               !child_trading_short_Strap.mlapp
           elseif stab == "Short Strip"
               %app.Lamp_3.Color = "green";
               !child_trading_short_Strip.mlapp
           elseif stab == "Short Strangle"
               %app.Lamp_3.Color = "green";
               !child_trading_short_Strangle.mlapp
           end

           if fluc == "Not interested"
               %app.Lamp_4.Color = "red";
           elseif fluc == "Short Butterfly Spread"
               %app.Lamp_4.Color = "green";
               !child_trading_short_butterfly.mlapp
           elseif fluc == "Long Straddle"
               %app.Lamp_4.Color = "green";
               !child_trading_long_Straddle.mlapp
           elseif fluc == "Long Strap"
               %app.Lamp_4.Color = "green";
               !child_trading_long_Strap.mlapp
           elseif fluc == "Long Strip"
               %app.Lamp_4.Color = "green";
               !child_trading_long_Strip.mlapp
           elseif fluc == "Long Strangle"
               %app.Lamp_4.Color = "green";
               !child_trading_long_Strangle.mlapp
           end
           delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TradingStrategiesLabel = uilabel(app.UIFigure);
            app.TradingStrategiesLabel.Text = 'Trading Strategies';
            app.IncreaseinpriceDropDownLabel = uilabel(app.UIFigure);
            app.IncreaseinpriceDropDownLabel.Text = 'Increase in price';
            app.IncreaseinpriceDropDown = uidropdown(app.UIFigure);
            app.IncreaseinpriceDropDown.Items = {'Not interested', 'Bull Spread'};
            app.IncreaseinpriceDropDown.Value = 'Not interested';
            app.DecreaseinpriceDropDownLabel = uilabel(app.UIFigure);
            app.DecreaseinpriceDropDownLabel.Text = 'Decrease in price';
            app.DecreaseinpriceDropDown = uidropdown(app.UIFigure);
            app.DecreaseinpriceDropDown.Items = {'Not interested', 'Bear Spread'};
            app.DecreaseinpriceDropDown.Value = 'Not interested';
            app.StablepriceDropDownLabel = uilabel(app.UIFigure);
            app.StablepriceDropDownLabel.Text = 'Stable price';
            app.StablepriceDropDown = uidropdown(app.UIFigure);
            app.StablepriceDropDown.Items = {'Not interested', 'Long Butterfly Spread', 'Short Straddle', 'Short Strap', 'Short Strip', 'Short Strangle'};
            app.StablepriceDropDown.Value = 'Not interested';
            app.PricefluctuationsDropDownLabel = uilabel(app.UIFigure);
            app.PricefluctuationsDropDownLabel.Text = 'Price fluctuations';
            app.PricefluctuationsDropDown = uidropdown(app.UIFigure);
            app.PricefluctuationsDropDown.Items = {'Not interested', 'Short Butterfly Spread', 'Long Straddle', 'Long Strap', 'Long Strip', 'Long Strangle'};
            app.PricefluctuationsDropDown.Value = 'Not interested';
            app.StarttotradeButton = uibutton(app.UIFigure, 'state');
            app.StarttotradeButton.ValueChangedFcn = createCallbackFcn(app, @StarttotradeButtonValueChanged, true);
            app.StarttotradeButton.Text = 'Start to trade';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TradingStrategies

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end