classdef child_trading_bull < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        annualsigmaEditField            matlab.ui.control.NumericEditField
        annualsigmaEditFieldLabel       matlab.ui.control.Label
        NumberofstepsSlider             matlab.ui.control.Slider
        NumberofstepsSliderLabel        matlab.ui.control.Label
        toEditField                     matlab.ui.control.EditField
        toEditFieldLabel                matlab.ui.control.Label
        ComputesigmafromEditField       matlab.ui.control.EditField
        ComputesigmafromEditFieldLabel  matlab.ui.control.Label
        ContinousinterestraterEditField  matlab.ui.control.NumericEditField
        ContinousinterestraterEditFieldLabel  matlab.ui.control.Label
        TimetoMatuirtyTEditField        matlab.ui.control.NumericEditField
        TimetoMatuirtyTEditFieldLabel   matlab.ui.control.Label
        DividendttmEditField            matlab.ui.control.NumericEditField
        DividendttmEditFieldLabel       matlab.ui.control.Label
        DividendrfqEditField            matlab.ui.control.NumericEditField
        DividendrfqEditFieldLabel       matlab.ui.control.Label
        StocktypeDropDown               matlab.ui.control.DropDown
        StocktypeDropDownLabel          matlab.ui.control.Label
        OptionStyleButtonGroup          matlab.ui.container.ButtonGroup
        AmericanButton                  matlab.ui.control.RadioButton
        EuropeanButton                  matlab.ui.control.RadioButton
        GobackButton                    matlab.ui.control.StateButton
        CalculateButton                 matlab.ui.control.StateButton
        TickerEditField                 matlab.ui.control.EditField
        TickerEditFieldLabel            matlab.ui.control.Label
        OTMOptionpriceEditField         matlab.ui.control.NumericEditField
        OTMOptionpriceEditFieldLabel    matlab.ui.control.Label
        ITMOptionpriceEditField         matlab.ui.control.NumericEditField
        ITMOptionpriceEditFieldLabel    matlab.ui.control.Label
        StrikeOTMEditField              matlab.ui.control.NumericEditField
        StrikeOTMEditFieldLabel         matlab.ui.control.Label
        ShortCallOptionOTMLabel         matlab.ui.control.Label
        StrikeITMEditField              matlab.ui.control.NumericEditField
        StrikeITMEditFieldLabel         matlab.ui.control.Label
        LongCallOptionITMLabel          matlab.ui.control.Label
        BullSpreadLabel                 matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,"center")
            app.UIFigure.Visible = 'on';
        end

        % Value changed function: CalculateButton
        function CalculateButtonValueChanged(app, event)
            TD =app.DividendttmEditField.Value;
      Drfq = app.DividendrfqEditField.Value;
      stock_type = app.StocktypeDropDown.Value;
      enddate = app.toEditField.Value;
      startdate = app.ComputesigmafromEditField.Value;
      r = app.ContinousinterestraterEditField.Value;
      T = app.TimetoMatuirtyTEditField.Value;
      KOTM = app.StrikeOTMEditField.Value;
      KITM = app.StrikeITMEditField.Value;
      ticker = app.TickerEditField.Value;
      n = round(app.NumberofstepsSlider.Value);
      cla(app.UIAxes) % CRUCIAL

      [S0, sigma] = yahoo(ticker, startdate, enddate);
      ST = linspace(0,2*S0,101);
      
      if app.AmericanButton.Value == true
        ITM_price = CRR_binomial_price_american_call(S0,KITM,T,sigma,r,n,stock_type,Drfq,TD);
        OTM_price = CRR_binomial_price_american_call(S0,KOTM,T,sigma,r,n,stock_type,Drfq,TD);
      else 
        ITM_price = BSM_c_4types(S0,KITM,T,sigma,r,stock_type,Drfq,TD);
        OTM_price = BSM_c_4types(S0,KOTM,T,sigma,r,stock_type,Drfq,TD);
      end     
                                                            
      ITM_profit = max(ST-KITM,0)- ITM_price;
      OTM_profit = min(KOTM-ST,0) + OTM_price;
      total_profit = ITM_profit + OTM_profit;
      
      app.annualsigmaEditField.Value = sigma;
      app.ITMOptionpriceEditField.Value =  ITM_price;
      app.OTMOptionpriceEditField.Value =  OTM_price;
      
      hold(app.UIAxes, "on")
      plot(app.UIAxes,ST,ITM_profit);
      plot(app.UIAxes,ST,OTM_profit);
      plot(app.UIAxes,ST,total_profit);
      legend(app.UIAxes,{'ITM profit','OTM profit','total profit'},'Location','northeast');
      hold(app.UIAxes, "off")
            
        end

        % Value changed function: GobackButton
        function GobackButtonValueChanged(app, event)
            delete(app)
            !TradingStrategies.mlapp
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Payoff of the strategy')
            xlabel(app.UIAxes, 'ST')
            ylabel(app.UIAxes, 'Payoff')
            zlabel(app.UIAxes, 'Z')
            app.BullSpreadLabel = uilabel(app.UIFigure);
            app.BullSpreadLabel.Text = 'Bull Spread';
            app.LongCallOptionITMLabel = uilabel(app.UIFigure);
            app.LongCallOptionITMLabel.Text = 'Long Call Option ITM:';
            app.StrikeITMEditFieldLabel = uilabel(app.UIFigure);
            app.StrikeITMEditFieldLabel.Text = 'Strike ITM';
            app.StrikeITMEditField = uieditfield(app.UIFigure, 'numeric');
            app.StrikeITMEditField.Value = 55;
            app.ShortCallOptionOTMLabel = uilabel(app.UIFigure);
            app.ShortCallOptionOTMLabel.Text = 'Short Call Option OTM:';
            app.StrikeOTMEditFieldLabel = uilabel(app.UIFigure);
            app.StrikeOTMEditFieldLabel.Text = 'Strike OTM';
            app.StrikeOTMEditField = uieditfield(app.UIFigure, 'numeric');
            app.StrikeOTMEditField.Value = 65;
            app.ITMOptionpriceEditFieldLabel = uilabel(app.UIFigure);
            app.ITMOptionpriceEditFieldLabel.Text = 'ITM Option price';
            app.ITMOptionpriceEditField = uieditfield(app.UIFigure, 'numeric');
            app.OTMOptionpriceEditFieldLabel = uilabel(app.UIFigure);
            app.OTMOptionpriceEditFieldLabel.Text = 'OTM Option price';
            app.OTMOptionpriceEditField = uieditfield(app.UIFigure, 'numeric');
            app.TickerEditFieldLabel = uilabel(app.UIFigure);
            app.TickerEditFieldLabel.Text = 'Ticker';
            app.TickerEditField = uieditfield(app.UIFigure, 'text');
            app.TickerEditField.Value = 'KO';
            app.CalculateButton = uibutton(app.UIFigure, 'state');
            app.CalculateButton.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonValueChanged, true);
            app.CalculateButton.Text = 'Calculate';
            app.GobackButton = uibutton(app.UIFigure, 'state');
            app.GobackButton.ValueChangedFcn = createCallbackFcn(app, @GobackButtonValueChanged, true);
            app.GobackButton.Text = 'Go back';
            app.OptionStyleButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionStyleButtonGroup.Title = 'Option Style';
            app.EuropeanButton = uiradiobutton(app.OptionStyleButtonGroup);
            app.EuropeanButton.Text = 'European';
            app.EuropeanButton.Value = true;
            app.AmericanButton = uiradiobutton(app.OptionStyleButtonGroup);
            app.AmericanButton.Text = 'American';
            app.StocktypeDropDownLabel = uilabel(app.UIFigure);
            app.StocktypeDropDownLabel.Text = 'Stock type';
            app.StocktypeDropDown = uidropdown(app.UIFigure);
            app.StocktypeDropDown.Items = {'Non-dividend-paying stock', 'Dividend-paying stock', 'Stock Index', 'Currency'};
            app.StocktypeDropDown.Value = 'Dividend-paying stock';
            app.DividendrfqEditFieldLabel = uilabel(app.UIFigure);
            app.DividendrfqEditFieldLabel.Text = 'Dividend/rf/q ';
            app.DividendrfqEditField = uieditfield(app.UIFigure, 'numeric');
            app.DividendrfqEditField.Value = 0.8;
            app.DividendttmEditFieldLabel = uilabel(app.UIFigure);
            app.DividendttmEditFieldLabel.Text = 'Dividend ttm';
            app.DividendttmEditField = uieditfield(app.UIFigure, 'numeric');
            app.DividendttmEditField.Value = 0.25;
            app.TimetoMatuirtyTEditFieldLabel = uilabel(app.UIFigure);
            app.TimetoMatuirtyTEditFieldLabel.Text = 'Time to Matuirty T';
            app.TimetoMatuirtyTEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimetoMatuirtyTEditField.Value = 0.5;
            app.ContinousinterestraterEditFieldLabel = uilabel(app.UIFigure);
            app.ContinousinterestraterEditFieldLabel.Text = 'Continous interest rate r';
            app.ContinousinterestraterEditField = uieditfield(app.UIFigure, 'numeric');
            app.ContinousinterestraterEditField.Value = 0.01;
            app.ComputesigmafromEditFieldLabel = uilabel(app.UIFigure);
            app.ComputesigmafromEditFieldLabel.Text = 'Compute sigma from';
            app.ComputesigmafromEditField = uieditfield(app.UIFigure, 'text');
            app.ComputesigmafromEditField.Value = '1-Jan-2018';
            app.toEditFieldLabel = uilabel(app.UIFigure);
            app.toEditFieldLabel.Text = 'to';
            app.toEditField = uieditfield(app.UIFigure, 'text');
            app.toEditField.Value = 'today';
            app.NumberofstepsSliderLabel = uilabel(app.UIFigure);
            app.NumberofstepsSliderLabel.Text = 'Number of steps';
            app.NumberofstepsSlider = uislider(app.UIFigure);
            app.NumberofstepsSlider.Limits = [0 20000];
            app.NumberofstepsSlider.MajorTicks = [0 2500 5000 7500 10000 12500 15000 17500 20000];
            app.NumberofstepsSlider.MajorTickLabels = {'0', '2500', '5000', '7500', '10000', '12500', '15000', '17500', ' 20000'};
            app.NumberofstepsSlider.MinorTicks = [1250 3750 6250 8750 11250 13750 16250 18750];
            app.annualsigmaEditFieldLabel = uilabel(app.UIFigure);
            app.annualsigmaEditFieldLabel.Text = 'annual sigma';
            app.annualsigmaEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = child_trading_bull

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end